classdef prover_m < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputtimestepintervalEditFieldLabel  matlab.ui.control.Label
        usetimestepforplottingSwitchLabel  matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        dimensionlessLabel              matlab.ui.control.Label
        proverLogo                      matlab.ui.control.Image
        MATLAB19842022TheMathWorksIncLabel  matlab.ui.control.Label
        LicensedunderGNUGPLLabel        matlab.ui.control.Label
        CriticalvelocitythresholdEditFieldLabel  matlab.ui.control.Label
        ModeloutputLabel                matlab.ui.control.Label
        SettlingEditFieldLabel          matlab.ui.control.Label
        CloudheightEditFieldLabel       matlab.ui.control.Label
        CloudwidthEditFieldLabel        matlab.ui.control.Label
        DynamicCollapseLabel            matlab.ui.control.Label
        ConvectiveDescentLabel          matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        CloudradiusEditFieldLabel       matlab.ui.control.Label
        StrippingEditFieldLabel         matlab.ui.control.Label
        LiveParametersLabel             matlab.ui.control.Label
        TimeEditFieldLabel              matlab.ui.control.Label
        commandfeedTextAreaLabel        matlab.ui.control.Label
        InputcaseDropDownLabel          matlab.ui.control.Label
        ModelinputLabel                 matlab.ui.control.Label
        byGundlachetal2023Label         matlab.ui.control.Label
        PROVERMGraphicalUserInterfaceLabel  matlab.ui.control.Label
        SedimentSettingsLabel           matlab.ui.control.Label
        a_lambdaTurbulenceDissipationLabel  matlab.ui.control.Label
        gammaDensitydifferenceLabel     matlab.ui.control.Label
        c_FricFrictionPhase2Label       matlab.ui.control.Label
        c_DragDragPhase2Label           matlab.ui.control.Label
        c_DDragPhase1Label              matlab.ui.control.Label
        c_MMassLabel                    matlab.ui.control.Label
        betaSettlingLabel               matlab.ui.control.Label
        alphaEntrainmentLabel           matlab.ui.control.Label
        CoefficientsLabel               matlab.ui.control.Label
        DumpinginstancesLabel           matlab.ui.control.Label
        PostdisposaldraftEditFieldLabel  matlab.ui.control.Label
        PredisposaldraftEditFieldLabel  matlab.ui.control.Label
        WaterdepthEditFieldLabel        matlab.ui.control.Label
        DisposalvolumeEditFieldLabel    matlab.ui.control.Label
        HopperSettingsLabel             matlab.ui.control.Label
        AmbientdensitybottomEditFieldLabel  matlab.ui.control.Label
        AmbientdensitytopEditFieldLabel  matlab.ui.control.Label
        AmbientyvelocityEditFieldLabel  matlab.ui.control.Label
        AmbientxvelocityEditFieldLabel  matlab.ui.control.Label
        AmbientSettingsLabel            matlab.ui.control.Label
        dumpUnitLabel                   matlab.ui.control.Label
        mLabel_16                       matlab.ui.control.Label
        mLabel_14                       matlab.ui.control.Label
        mLabel_13                       matlab.ui.control.Label
        mLabel_15                       matlab.ui.control.Label
        mLabel_12                       matlab.ui.control.Label
        mLabel_10                       matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        mLabel_8                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        kgmLabel                        matlab.ui.control.Label
        kgmLabel_2                      matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        SettlingEditField               matlab.ui.control.NumericEditField
        CloudheightEditField            matlab.ui.control.NumericEditField
        CloudwidthEditField             matlab.ui.control.NumericEditField
        StrippingEditField              matlab.ui.control.NumericEditField
        CloudradiusEditField            matlab.ui.control.NumericEditField
        TimeEditField                   matlab.ui.control.NumericEditField
        commandfeedTextArea             matlab.ui.control.TextArea
        ResetinputButton                matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SaveasButton_2                  matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        usetimestepforplottingSwitch    matlab.ui.control.Switch
        OutputtimestepintervalEditField  matlab.ui.control.NumericEditField
        ResetSedimentdataButton         matlab.ui.control.Button
        CODE_NAME                       matlab.ui.control.Table
        psi_stripStrippingEditField     matlab.ui.control.NumericEditField
        c_FFrictionPhase2EditField      matlab.ui.control.NumericEditField
        c_D2DragPhase2EditField         matlab.ui.control.NumericEditField
        c_D1DragPhase1EditField         matlab.ui.control.NumericEditField
        c_MMassEditField_2              matlab.ui.control.NumericEditField
        alpha_2EntrainmentPhase2EditField  matlab.ui.control.NumericEditField
        alpha_1EntrainmentEditField     matlab.ui.control.NumericEditField
        deltaSettlingEditField          matlab.ui.control.NumericEditField
        info_settling                   matlab.ui.control.Button
        PostdisposaldraftEditField      matlab.ui.control.NumericEditField
        PredisposaldraftEditField       matlab.ui.control.NumericEditField
        DumpinginstancesEditField       matlab.ui.control.NumericEditField
        DisposalvolumeEditField         matlab.ui.control.NumericEditField
        CriticalvelocitythresholdEditField  matlab.ui.control.NumericEditField
        AmbientdensitybottomEditField   matlab.ui.control.NumericEditField
        AmbientdensitytopEditField      matlab.ui.control.NumericEditField
        AmbientyvelocityEditField       matlab.ui.control.NumericEditField
        AmbientxvelocityEditField       matlab.ui.control.NumericEditField
        WaterdepthEditField             matlab.ui.control.NumericEditField
        InputcaseDropDown               matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function resetFeed(app,event)
            app.commandfeedTextArea.Value = [''];
            app.TimeEditField.Value = 0;
            app.CloudradiusEditField.Value = 0;
            app.StrippingEditField.Value = 0;
            app.CloudwidthEditField.Value = 0;
            app.CloudheightEditField.Value = 0;
            app.SettlingEditField.Value = 0;
            cla(app.UIAxes)
        end

        function resetInput(app,event)
            % read current input case
            case_name = app.InputcaseDropDown.Value;
            % load last saved version of current input case
            LoadInputCase(app,case_name);
        end

        function [t_save_cmb] = SaveParameters(app)
            t_save = zeros(20,1);

            % Ambient Settings
            t_save(1) = app.WaterdepthEditField.Value;
            t_save(2) = app.AmbientxvelocityEditField.Value;
            t_save(3) = app.AmbientyvelocityEditField.Value;
            t_save(4) = app.AmbientdensitytopEditField.Value;
            t_save(5) = app.AmbientdensitybottomEditField.Value;
            t_save(6) = app.CriticalvelocitythresholdEditField.Value; 

            % Hopper Settings
            t_save(8) = app.DisposalvolumeEditField.Value;
            t_save(9) = app.DumpinginstancesEditField.Value;
            t_save(10) = app.PredisposaldraftEditField.Value;
            t_save(11) = app.PostdisposaldraftEditField.Value;
            t_save(12) = app.deltaSettlingEditField.Value;

            % Coefficients
            t_save(14) = app.alpha_1EntrainmentEditField.Value;
            t_save(15) = app.alpha_2EntrainmentPhase2EditField.Value;
            t_save(16) = app.c_MMassEditField_2.Value;
            t_save(17) = app.c_D1DragPhase1EditField.Value;
            t_save(18) = app.c_D2DragPhase2EditField.Value;
            t_save(19) = app.c_FFrictionPhase2EditField.Value;
            t_save(20) = app.psi_stripStrippingEditField.Value;

            % Model output
            t_save(21) = app.OutputtimestepintervalEditField.Value;
            t_save(22) = app.usetimestepforplottingSwitch.Value;
            
            % Preparing descriptive text
            varsA = {'WaterDepth', 'AmbientXVelocity', 'AmbientYVelocity', 'AmbientDensityTop', 'AmbientDensityBottom', 'CriticalVelocity'};
            varsH = {'DisposalVolume', 'DumpingInstances', 'PreDisposalDraft', 'PostDisposalDraft', 'deltaSettling'};
            varsC = {'alpha1Entrainment', 'alpha2Entrainment', 'cMMass', 'cD1Drag', 'cD2Drag', 'cFFriction', 'psiStrip'};
            varsS = {'Sediment', 'Density', 'volFraction', 'FallVelocity', 'VoidRatio', 'CritShearStress', 'cohesive', 'stripping'};
            varsO = {'TimeStepInterval', 'UseTimeStepPlot'};
            
            headers = {'%% PROVER-M input for the graphical user interface', '%% created by Jannek Gundlach, Maximilian Behnke', '%% Model input', '% Ambient Settings', '% Hopper Settings', '% Coefficients', '% Sediment Settings', '%% Model output'};
            namesS = {'Sand', 'Silt', 'Clay'};

            % combine arrays
            t_A = [varsA',num2cell(t_save(1:6))];
            t_H = [varsH',num2cell(t_save(8:12))];
            t_C = [varsC',num2cell(t_save(14:20))];
            t_O = [varsO',num2cell(t_save(21:22))];
            
            t_sed = [table2array(app.CODE_NAME.Data(:,2:end))]; 

            t_save_cmb = cell(37,8);
            t_save_cmb(1:28,1:2) = [headers(1) {''}; headers(2) {''}; {''} {''}; headers(3) {''};headers(4) {''}; t_A; {''} {''}; headers(5) {''};t_H; {''} {''}; headers(6) {''};t_C; {''} {''}; ];
            t_save_cmb(29:33,1:8) = [headers(7) cell(1,7); varsS; namesS' num2cell(t_sed)];
            t_save_cmb(34:37,1:2) = [{''} {''}; headers(8) {''};t_O;];

        end

        function LoadInputCaseMenu(app)
            [folder_main, folder_current] = setcurrentfolder(app);
            inputCases = dir([folder_main, '\input\' '*.txt']); %search for .txt dateien
            app.InputcaseDropDown.Items = {inputCases.name}; % append to app.InputcaseDropDown.Items
        end


        function LoadInputCase(app,case_name)
           
            [folder_main, folder_current] = setcurrentfolder(app);
            t_def = readtable([folder_main,'\input\' case_name],'PreserveVariableNames',true);
            t_arr = table2array(t_def(4:end,2:end));

            app.InputcaseDropDown.Value = case_name;

            % Ambient Settings
            app.WaterdepthEditField.Value = t_arr(1,1);
            app.AmbientxvelocityEditField.Value = t_arr(2,1);
            app.AmbientyvelocityEditField.Value = t_arr(3,1);
            app.AmbientdensitytopEditField.Value = t_arr(4,1);
            app.AmbientdensitybottomEditField.Value = t_arr(5,1);
            app.CriticalvelocitythresholdEditField.Value = t_arr(6,1); 

            % Hopper Settings
            app.DisposalvolumeEditField.Value = t_arr(8,1);
            app.DumpinginstancesEditField.Value = t_arr(9,1);
            app.PredisposaldraftEditField.Value = t_arr(10,1);
            app.PostdisposaldraftEditField.Value = t_arr(11,1);
            app.deltaSettlingEditField.Value = t_arr(12,1);

            % Coefficients
            app.alpha_1EntrainmentEditField.Value = t_arr(14,1);
            app.alpha_2EntrainmentPhase2EditField.Value = t_arr(15,1);
            app.c_MMassEditField_2.Value = t_arr(16,1);
            app.c_D1DragPhase1EditField.Value = t_arr(17,1);
            app.c_D2DragPhase2EditField.Value = t_arr(18,1);
            app.c_FFrictionPhase2EditField.Value = t_arr(19,1);
            app.psi_stripStrippingEditField.Value = t_arr(20,1);
            
            % Sediment Settings
            app.CODE_NAME.Data = t_def(26:28,:);

            % Model output
            app.OutputtimestepintervalEditField.Value = t_arr(27,1);
            app.usetimestepforplottingSwitch.Value = t_arr(28,1);

        end

        function [folder_main, folder_current] = setcurrentfolder(app)
            cs = mfilename('fullpath');
            cd(fileparts((cs)));

            folder_current = fileparts((cs));
            folder_main = [fileparts(cs),'\..'];
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [folder_main, folder_current] = setcurrentfolder(app);

            resetFeed(app);
            LoadInputCaseMenu(app);

            case_name = 'Default_case.txt';
            LoadInputCase(app,case_name);
        end

        % Button pushed function: ResetSedimentdataButton
        function ResetSedimentdataButtonPushed(app, event)
            % read current input case
            case_name = app.InputcaseDropDown.Value;
            [folder_main, folder_current] = setcurrentfolder(app);
            t_def = readtable([folder_main,'\input\' case_name],'PreserveVariableNames',true);
            t_arr = table2array(t_def(4:end,2:end)); 

            % Sediment Settings
            app.CODE_NAME.Data = t_def(26:28,:);
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % Saving input parameters
            [t_save_cmb] = SaveParameters(app);
            
            [folder_main, folder_current] = setcurrentfolder(app);
            file_info = {folder_main; folder_current};
            [fname,pname] = uiputfile([folder_main,'\input\.txt'],'Save model input');
            writecell(t_save_cmb,[folder_main,'\input\', fname], 'Delimiter', '\t'); 

            LoadInputCaseMenu(app);
            app.InputcaseDropDown.Value = fname;

            % transfer input parameters for main function
            d = app.WaterdepthEditField.Value;
            amb(1) = app.AmbientxvelocityEditField.Value;
            amb(2) = app.AmbientyvelocityEditField.Value;
            rho_amb_init(1) = app.AmbientdensitytopEditField.Value;
            rho_amb_init(2) = app.AmbientdensitybottomEditField.Value;
            z_i(1) = 0; 
            z_i(2) = app.WaterdepthEditField.Value; 
            
            u_amb_crit = app.CriticalvelocitythresholdEditField.Value;

            mass = app.DisposalvolumeEditField.Value;
            draft_predisp = app.PredisposaldraftEditField.Value;
            draft_postdisp = app.PostdisposaldraftEditField.Value;
            n_dump = app.DumpinginstancesEditField.Value;

            Para.ALPHA0 = app.alpha_1EntrainmentEditField.Value;
            Para.ALPHAB = app.alpha_2EntrainmentPhase2EditField.Value;
            Para.DELTA = app.deltaSettlingEditField.Value;
            Para.CM = app.c_MMassEditField_2.Value;
            Para.CD = app.c_D1DragPhase1EditField.Value;
            Para.CDRAG = app.c_D2DragPhase2EditField.Value;
            Para.CFRIC = app.c_FFrictionPhase2EditField.Value;
            Para.STRIP = app.psi_stripStrippingEditField.Value;
            Para.KV = 1.0; 

            Sediment.name = [{"SAND"} {"SILT"} {"CLAY"}];
            Sediment.rho = table2array(app.CODE_NAME.Data(:,2));
            Sediment.frac = table2array(app.CODE_NAME.Data(:,3)) / 100;
            Sediment.ws = table2array(app.CODE_NAME.Data(:,4));
            Sediment.void = table2array(app.CODE_NAME.Data(:,5));
            Sediment.shear = table2array(app.CODE_NAME.Data(:,6));
            Sediment.cohe = table2array(app.CODE_NAME.Data(:,7));
            Sediment.strip = table2array(app.CODE_NAME.Data(:,8));

            % transfer output parameters for main function
            dt_sim = app.OutputtimestepintervalEditField.Value;
            t_sim_istep = app.usetimestepforplottingSwitch.Value;

            resetFeed(app);

            prover_m_main(app,file_info,d,amb,rho_amb_init,z_i,u_amb_crit,mass,draft_predisp,draft_postdisp,n_dump,Para,Sediment,dt_sim,t_sim_istep);
        end

        % Button pushed function: SaveasButton_2
        function SaveasButton_2Pushed(app, event)
            [t_save_cmb] = SaveParameters(app);

            [folder_main, folder_current] = setcurrentfolder(app);
            [fname,pname] = uiputfile([folder_main,'\input\.txt'],'Save model input');
            writecell(t_save_cmb,[folder_main,'\input\', fname], 'Delimiter', '\t'); 

            LoadInputCaseMenu(app);
            app.InputcaseDropDown.Value = fname;

            resetFeed(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [folder_main, folder_current] = setcurrentfolder(app);
            [filename, path] = uigetfile([folder_main,'\input\*.txt'],'Load model input');
            app.InputcaseDropDown.Value = filename;

            t_def = readtable([path filename],'PreserveVariableNames',true);
            t_arr = table2array(t_def(4:end,2:end)); 

            % Ambient Settings
            app.WaterdepthEditField.Value = t_arr(1,1);
            app.AmbientxvelocityEditField.Value = t_arr(2,1);
            app.AmbientyvelocityEditField.Value = t_arr(3,1);
            app.AmbientdensitytopEditField.Value = t_arr(4,1);
            app.AmbientdensitybottomEditField.Value = t_arr(5,1);
            app.CriticalvelocitythresholdEditField.Value = t_arr(6,1); 

            % Hopper Settings
            app.DisposalvolumeEditField.Value = t_arr(8,1);
            app.DumpinginstancesEditField.Value = t_arr(9,1);
            app.PredisposaldraftEditField.Value = t_arr(10,1);
            app.PostdisposaldraftEditField.Value = t_arr(11,1);
            app.deltaSettlingEditField.Value = t_arr(12,1);

            % Coefficients
            app.alpha_1EntrainmentEditField.Value = t_arr(14,1);
            app.alpha_2EntrainmentPhase2EditField.Value = t_arr(15,1);
            app.c_MMassEditField_2.Value = t_arr(16,1);
            app.c_D1DragPhase1EditField.Value = t_arr(17,1);
            app.c_D2DragPhase2EditField.Value = t_arr(18,1);
            app.c_FFrictionPhase2EditField.Value = t_arr(19,1);
            app.psi_stripStrippingEditField.Value = t_arr(20,1);
            
            % Sediment Settings
            app.CODE_NAME.Data = t_def(26:28,:);

            % transfer output parameters for main function
            app.OutputtimestepintervalEditField.Value = t_arr(27,1);
            app.usetimestepforplottingSwitch.Value = t_arr(28,1);

            resetFeed(app);
        end

        % Button pushed function: ResetinputButton
        function ResetinputButtonPushed(app, event)
            resetInput(app,event);
            resetFeed(app);
        end

        % Value changed function: PredisposaldraftEditField
        function PredisposaldraftEditFieldValueChanged(app, event)
            value = app.PredisposaldraftEditField.Value;
            if value >= app.WaterdepthEditField.Value - 1
                app.PredisposaldraftEditField.Value = app.WaterdepthEditField.Value - 1;
            end
        end

        % Value changed function: PostdisposaldraftEditField
        function PostdisposaldraftEditFieldValueChanged(app, event)
            value = app.PostdisposaldraftEditField.Value;
            if value >= app.PredisposaldraftEditField.Value
                app.PostdisposaldraftEditField.Value = app.PredisposaldraftEditField.Value - 1;
            end
        end

        % Callback function
        function InputcaseDropDownOpening(app, event)
            LoadInputCaseMenu(app);
        end

        % Value changed function: InputcaseDropDown
        function InputcaseDropDownValueChanged(app, event)
            case_name = app.InputcaseDropDown.Value;
            LoadInputCase(app,case_name);
        end

        % Button pushed function: info_settling
        function info_settlingButtonPushed(app, event)
            helpdlg({'δ < 0: the settling reduction factor is determined based on the defined critical shear-stress for deposition', ...
                '0 < δ < 1: the settling reduction factor is equivalent to the deposition efficiency δ'},'Settling - deposition efficiency δ');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cloud propagation')
            xlabel(app.UIAxes, 'Lateral spread [m]')
            ylabel(app.UIAxes, 'Water depth [m]')
            zlabel(app.UIAxes, 'Z')
            app.InputcaseDropDown = uidropdown(app.UIFigure);
            app.InputcaseDropDown.Items = {'Select case', 'Default Case', 'Delo et al (1987)'};
            app.InputcaseDropDown.ValueChangedFcn = createCallbackFcn(app, @InputcaseDropDownValueChanged, true);
            app.InputcaseDropDown.Value = 'Select case';
            app.WaterdepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaterdepthEditField.Limits = [0 100];
            app.WaterdepthEditField.Value = 40;
            app.AmbientxvelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmbientxvelocityEditField.Limits = [-2.5 2.5];
            app.AmbientyvelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmbientyvelocityEditField.Limits = [-2.5 2.5];
            app.AmbientdensitytopEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmbientdensitytopEditField.Limits = [1000 1050];
            app.AmbientdensitytopEditField.ValueDisplayFormat = '%.1f';
            app.AmbientdensitytopEditField.Value = 1010;
            app.AmbientdensitybottomEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmbientdensitybottomEditField.Limits = [1000 1050];
            app.AmbientdensitybottomEditField.ValueDisplayFormat = '%.1f';
            app.AmbientdensitybottomEditField.Value = 1010.3;
            app.CriticalvelocitythresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.CriticalvelocitythresholdEditField.Limits = [0 0.1];
            app.CriticalvelocitythresholdEditField.ValueDisplayFormat = '%.2f';
            app.CriticalvelocitythresholdEditField.Value = 0.05;
            app.DisposalvolumeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisposalvolumeEditField.Limits = [100 10000];
            app.DisposalvolumeEditField.Value = 3702;
            app.DumpinginstancesEditField = uieditfield(app.UIFigure, 'numeric');
            app.DumpinginstancesEditField.Limits = [0 5];
            app.DumpinginstancesEditField.ValueDisplayFormat = '%.0f';
            app.DumpinginstancesEditField.Value = 1;
            app.PredisposaldraftEditField = uieditfield(app.UIFigure, 'numeric');
            app.PredisposaldraftEditField.Limits = [0 15];
            app.PredisposaldraftEditField.ValueDisplayFormat = '%.1f';
            app.PredisposaldraftEditField.ValueChangedFcn = createCallbackFcn(app, @PredisposaldraftEditFieldValueChanged, true);
            app.PostdisposaldraftEditField = uieditfield(app.UIFigure, 'numeric');
            app.PostdisposaldraftEditField.Limits = [0 15];
            app.PostdisposaldraftEditField.ValueDisplayFormat = '%.1f';
            app.PostdisposaldraftEditField.ValueChangedFcn = createCallbackFcn(app, @PostdisposaldraftEditFieldValueChanged, true);
            app.info_settling = uibutton(app.UIFigure, 'push');
            app.info_settling.ButtonPushedFcn = createCallbackFcn(app, @info_settlingButtonPushed, true);
            app.info_settling.Text = 'i';
            app.deltaSettlingEditField = uieditfield(app.UIFigure, 'numeric');
            app.deltaSettlingEditField.Limits = [-1 1];
            app.alpha_1EntrainmentEditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha_1EntrainmentEditField.Limits = [0 1];
            app.alpha_1EntrainmentEditField.Value = 0.28;
            app.alpha_2EntrainmentPhase2EditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha_2EntrainmentPhase2EditField.Limits = [0 1];
            app.alpha_2EntrainmentPhase2EditField.Value = 0.15;
            app.c_MMassEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.c_MMassEditField_2.Limits = [0.05 1];
            app.c_MMassEditField_2.Value = 1;
            app.c_D1DragPhase1EditField = uieditfield(app.UIFigure, 'numeric');
            app.c_D1DragPhase1EditField.Limits = [0 10];
            app.c_D1DragPhase1EditField.Value = 5;
            app.c_D2DragPhase2EditField = uieditfield(app.UIFigure, 'numeric');
            app.c_D2DragPhase2EditField.Limits = [0 1];
            app.c_D2DragPhase2EditField.Value = 0.05;
            app.c_FFrictionPhase2EditField = uieditfield(app.UIFigure, 'numeric');
            app.c_FFrictionPhase2EditField.Limits = [0 1];
            app.c_FFrictionPhase2EditField.Value = 0.01;
            app.psi_stripStrippingEditField = uieditfield(app.UIFigure, 'numeric');
            app.psi_stripStrippingEditField.Limits = [0 0.05];
            app.psi_stripStrippingEditField.Value = 0.001;
            app.CODE_NAME = uitable(app.UIFigure);
            app.CODE_NAME.ColumnName = {'Sediment'; 'Density [kg/m³]'; 'Volum. Fraction [%]'; 'Fall velocity [m/s]'; 'Void Ratio'; 'Critical shear stress [kg/m²]'; 'Cohesive?'; 'Stripping?'};
            app.CODE_NAME.RowName = {};
            app.CODE_NAME.ColumnEditable = true;
            app.ResetSedimentdataButton = uibutton(app.UIFigure, 'push');
            app.ResetSedimentdataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSedimentdataButtonPushed, true);
            app.ResetSedimentdataButton.Text = 'Reset Sediment data';
            app.OutputtimestepintervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputtimestepintervalEditField.Limits = [0 5];
            app.OutputtimestepintervalEditField.ValueDisplayFormat = '%11.3g';
            app.OutputtimestepintervalEditField.Value = 0.2;
            app.usetimestepforplottingSwitch = uiswitch(app.UIFigure, 'slider');
            app.usetimestepforplottingSwitch.Items = {'No', 'Yes'};
            app.usetimestepforplottingSwitch.ItemsData = [0 1];
            app.usetimestepforplottingSwitch.Value = 0;
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SaveasButton_2 = uibutton(app.UIFigure, 'push');
            app.SaveasButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveasButton_2Pushed, true);
            app.SaveasButton_2.Text = 'Save as..';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load..';
            app.ResetinputButton = uibutton(app.UIFigure, 'push');
            app.ResetinputButton.ButtonPushedFcn = createCallbackFcn(app, @ResetinputButtonPushed, true);
            app.ResetinputButton.Text = 'Reset input';
            app.commandfeedTextArea = uitextarea(app.UIFigure);
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.Editable = 'off';
            app.CloudradiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloudradiusEditField.Editable = 'off';
            app.StrippingEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrippingEditField.Editable = 'off';
            app.CloudwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloudwidthEditField.Editable = 'off';
            app.CloudheightEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloudheightEditField.Editable = 'off';
            app.SettlingEditField = uieditfield(app.UIFigure, 'numeric');
            app.SettlingEditField.Editable = 'off';
            app.msLabel_2 = uilabel(app.UIFigure);
            app.msLabel_2.Text = '[m/s]';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = '[m/s]';
            app.msLabel_3 = uilabel(app.UIFigure);
            app.msLabel_3.Text = '[m/s]';
            app.kgmLabel_2 = uilabel(app.UIFigure);
            app.kgmLabel_2.Text = '[kg/m³]';
            app.kgmLabel = uilabel(app.UIFigure);
            app.kgmLabel.Text = '[kg/m³]';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '[m]';
            app.mLabel_8 = uilabel(app.UIFigure);
            app.mLabel_8.Text = '[m³]';
            app.mLabel_9 = uilabel(app.UIFigure);
            app.mLabel_9.Text = '[m]';
            app.mLabel_10 = uilabel(app.UIFigure);
            app.mLabel_10.Text = '[m]';
            app.mLabel_12 = uilabel(app.UIFigure);
            app.mLabel_12.Text = '[m³]';
            app.mLabel_15 = uilabel(app.UIFigure);
            app.mLabel_15.Text = '[m]';
            app.mLabel_13 = uilabel(app.UIFigure);
            app.mLabel_13.Text = '[m]';
            app.mLabel_14 = uilabel(app.UIFigure);
            app.mLabel_14.Text = '[m]';
            app.mLabel_16 = uilabel(app.UIFigure);
            app.mLabel_16.Text = '[m³]';
            app.dumpUnitLabel = uilabel(app.UIFigure);
            app.dumpUnitLabel.Text = '[#]';
            app.AmbientSettingsLabel = uilabel(app.UIFigure);
            app.AmbientSettingsLabel.Text = 'Ambient Settings';
            app.AmbientxvelocityEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientxvelocityEditFieldLabel.Interpreter = 'latex';
            app.AmbientxvelocityEditFieldLabel.Text = 'Ambient x-velocity';
            app.AmbientyvelocityEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientyvelocityEditFieldLabel.Interpreter = 'latex';
            app.AmbientyvelocityEditFieldLabel.Text = 'Ambient y-velocity';
            app.AmbientdensitytopEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientdensitytopEditFieldLabel.Interpreter = 'latex';
            app.AmbientdensitytopEditFieldLabel.Text = 'Ambient density (top)';
            app.AmbientdensitybottomEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientdensitybottomEditFieldLabel.Interpreter = 'latex';
            app.AmbientdensitybottomEditFieldLabel.Text = 'Ambient density (bottom)';
            app.HopperSettingsLabel = uilabel(app.UIFigure);
            app.HopperSettingsLabel.Text = 'Hopper Settings';
            app.DisposalvolumeEditFieldLabel = uilabel(app.UIFigure);
            app.DisposalvolumeEditFieldLabel.Interpreter = 'latex';
            app.DisposalvolumeEditFieldLabel.Text = 'Disposal volume';
            app.WaterdepthEditFieldLabel = uilabel(app.UIFigure);
            app.WaterdepthEditFieldLabel.Interpreter = 'latex';
            app.WaterdepthEditFieldLabel.Text = 'Water depth';
            app.PredisposaldraftEditFieldLabel = uilabel(app.UIFigure);
            app.PredisposaldraftEditFieldLabel.Interpreter = 'latex';
            app.PredisposaldraftEditFieldLabel.Text = 'Pre-disposal draft';
            app.PostdisposaldraftEditFieldLabel = uilabel(app.UIFigure);
            app.PostdisposaldraftEditFieldLabel.Interpreter = 'latex';
            app.PostdisposaldraftEditFieldLabel.Text = 'Post-disposal draft';
            app.DumpinginstancesLabel = uilabel(app.UIFigure);
            app.DumpinginstancesLabel.Interpreter = 'latex';
            app.DumpinginstancesLabel.Text = 'Dumping instances';
            app.CoefficientsLabel = uilabel(app.UIFigure);
            app.CoefficientsLabel.Text = 'Coefficients';
            app.alphaEntrainmentLabel = uilabel(app.UIFigure);
            app.alphaEntrainmentLabel.Interpreter = 'latex';
            app.alphaEntrainmentLabel.Text = '$\alpha_1$ - Entrainment';
            app.betaSettlingLabel = uilabel(app.UIFigure);
            app.betaSettlingLabel.Interpreter = 'latex';
            app.betaSettlingLabel.Text = '$\alpha_2$ - Entrainment (Phase 2)';
            app.c_MMassLabel = uilabel(app.UIFigure);
            app.c_MMassLabel.Interpreter = 'latex';
            app.c_MMassLabel.Text = '$\delta$ - Settling';
            app.c_DDragPhase1Label = uilabel(app.UIFigure);
            app.c_DDragPhase1Label.Interpreter = 'latex';
            app.c_DDragPhase1Label.Text = '$c_M$ - Mass';
            app.c_DragDragPhase2Label = uilabel(app.UIFigure);
            app.c_DragDragPhase2Label.Interpreter = 'latex';
            app.c_DragDragPhase2Label.Text = '$c_{D1}$ - Drag (Phase 1)';
            app.c_FricFrictionPhase2Label = uilabel(app.UIFigure);
            app.c_FricFrictionPhase2Label.Interpreter = 'latex';
            app.c_FricFrictionPhase2Label.Text = '$c_{D2}$ - Drag (Phase 2)';
            app.gammaDensitydifferenceLabel = uilabel(app.UIFigure);
            app.gammaDensitydifferenceLabel.Interpreter = 'latex';
            app.gammaDensitydifferenceLabel.Text = '$c_{F}$ - Friction (Phase 2)';
            app.a_lambdaTurbulenceDissipationLabel = uilabel(app.UIFigure);
            app.a_lambdaTurbulenceDissipationLabel.Interpreter = 'latex';
            app.a_lambdaTurbulenceDissipationLabel.Text = '$\psi_{strip}$ - Stripping';
            app.SedimentSettingsLabel = uilabel(app.UIFigure);
            app.SedimentSettingsLabel.Text = 'Sediment Settings';
            app.PROVERMGraphicalUserInterfaceLabel = uilabel(app.UIFigure);
            app.PROVERMGraphicalUserInterfaceLabel.Text = 'PROVER-M - Graphical User Interface';
            app.byGundlachetal2023Label = uilabel(app.UIFigure);
            app.byGundlachetal2023Label.Text = 'by Gundlach et al. (2023)';
            app.ModelinputLabel = uilabel(app.UIFigure);
            app.ModelinputLabel.Text = 'Model input';
            app.InputcaseDropDownLabel = uilabel(app.UIFigure);
            app.InputcaseDropDownLabel.Text = 'Input case';
            app.commandfeedTextAreaLabel = uilabel(app.UIFigure);
            app.commandfeedTextAreaLabel.Text = 'command feed';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.LiveParametersLabel = uilabel(app.UIFigure);
            app.LiveParametersLabel.Text = 'Live Parameters';
            app.StrippingEditFieldLabel = uilabel(app.UIFigure);
            app.StrippingEditFieldLabel.Text = 'Stripping';
            app.CloudradiusEditFieldLabel = uilabel(app.UIFigure);
            app.CloudradiusEditFieldLabel.Text = 'Cloud radius';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '[s]';
            app.ConvectiveDescentLabel = uilabel(app.UIFigure);
            app.ConvectiveDescentLabel.Text = 'Convective Descent';
            app.DynamicCollapseLabel = uilabel(app.UIFigure);
            app.DynamicCollapseLabel.Text = 'Dynamic Collapse';
            app.CloudwidthEditFieldLabel = uilabel(app.UIFigure);
            app.CloudwidthEditFieldLabel.Text = 'Cloud width';
            app.CloudheightEditFieldLabel = uilabel(app.UIFigure);
            app.CloudheightEditFieldLabel.Text = 'Cloud height';
            app.SettlingEditFieldLabel = uilabel(app.UIFigure);
            app.SettlingEditFieldLabel.Text = 'Settling';
            app.ModeloutputLabel = uilabel(app.UIFigure);
            app.ModeloutputLabel.Text = 'Model output';
            app.CriticalvelocitythresholdEditFieldLabel = uilabel(app.UIFigure);
            app.CriticalvelocitythresholdEditFieldLabel.Interpreter = 'latex';
            app.CriticalvelocitythresholdEditFieldLabel.Text = 'Critical velocity threshold';
            app.LicensedunderGNUGPLLabel = uilabel(app.UIFigure);
            app.LicensedunderGNUGPLLabel.Text = 'Licensed under GNU GPL';
            app.MATLAB19842022TheMathWorksIncLabel = uilabel(app.UIFigure);
            app.MATLAB19842022TheMathWorksIncLabel.Text = 'MATLAB®. © 1984 - 2022 The MathWorks, Inc';
            app.proverLogo = uiimage(app.UIFigure);
            app.proverLogo.ImageSource = fullfile(pathToMLAPP, 'images', 'Prover-Logo.png');
            app.dimensionlessLabel = uilabel(app.UIFigure);
            app.dimensionlessLabel.Text = '[-]';
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = '[s]';
            app.usetimestepforplottingSwitchLabel = uilabel(app.UIFigure);
            app.usetimestepforplottingSwitchLabel.Interpreter = 'latex';
            app.usetimestepforplottingSwitchLabel.Text = 'use time step for plotting';
            app.OutputtimestepintervalEditFieldLabel = uilabel(app.UIFigure);
            app.OutputtimestepintervalEditFieldLabel.Interpreter = 'latex';
            app.OutputtimestepintervalEditFieldLabel.Text = 'Output time step interval';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prover_m

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end